classdef PatternRecognition5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        PatternRecognitionTab      matlab.ui.container.Tab
        Step1ChooseImagewithObviousLineButton  matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        Step3GetintensitiesButton  matlab.ui.control.Button
        right                      matlab.ui.control.Button
        left                       matlab.ui.control.Button
        Step2LoadFolderwithImagesButton  matlab.ui.control.Button
        ImageSlider                matlab.ui.control.Slider
        ImageLabel                 matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        ImageAdjustTab             matlab.ui.container.Tab
        LoadFileButton             matlab.ui.control.Button
    end


    properties (Access = private)
        myFolder
        filePattern
        tifFiles
        play
        numImages
        playTimer
        finalX1
        finalY1
        finalX2
        finalY2
        Label
        centroids
        baseCentroids
        aX1
        aX2
        aY1
        aY2
    end

    methods (Access = private)
        function displayImage(app, index)%displays image in UI axes
            set(app.ImageSlider, 'value', index);
            file = app.tifFiles(round(index));
            filePath = fullfile(file.folder, file.name);
            imageArray = imread(filePath);
            app.Label.Text = file.name;
            app.ImageLabel.Text = round(index)+ "/" +app.numImages;

            %global imageshown
            %imageshown = imageArray;
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);

            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end

        %crops image file into individual cells, finds centroid & line in each cell
        function cellCrop(app, fname)
            
            I= imread(fname);
            tempC = ImageUtil.locateCentroids(I, false, fname);
            %could possibly skip this line, only do findLines once (needs
            %testing)
            [tempC,~, ~, ~, ~]= ImageUtil.findLines(I, tempC, fname);
            %adjust centroids for the cells with a line in it. redo line detection using the updated base
            [bw,tempC1 ]= ImageUtil.adjustCentroids(I, tempC);
            [app.baseCentroids, app.finalX1, app.finalX2, app.finalY1, app.finalY2] =ImageUtil.findLines(I, tempC1,fname);
        
            ImageUtil.syncImage(I, fname, NaN, ...
                app.baseCentroids, app.finalX1, app.finalX2, app.finalY1, app.finalY2, bw, false);
        end

        function dispNext(app)
            if not(app.numImages)
                return;
            end

            y = min(app.ImageSlider.Value+1, app.numImages);
            displayImage(app, y);
        end
 

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes,'DataAspectRatio',[2 2 1])
            app.play = false;

            app.numImages = 0;
            app.playTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.5, 'TimerFcn', @(~, ~) app.dispNext());
            try
                rmdir 'Pattern Recognition'/tempFold s
            catch
                disp 'No Pattern Recognition/tempFold folder to delete'
            end
            try
                rmdir 'Pattern Recognition'/updatedImages s
            catch
                disp 'No Pattern Recognition/updatedImages folder to delete'
            end

        end

        % Button pushed function: Step2LoadFolderwithImagesButton
        function LoadFile(app, event)
            try

                app.myFolder = uigetdir('C:');  % get file

                figure(app.UIFigure); % focus window again
                app.filePattern = '.tif';
                app.tifFiles = dir([app.myFolder '/*' app.filePattern]);
                app.numImages = length(app.tifFiles); % takes length and adjusts the slider
                app.ImageSlider.Limits = [1 app.numImages];

                if not(app.numImages)
                    return;
                end


                set(app.ImageSlider, 'value', 1) % sets slider
                mkdir("Pattern Recognition/tempFold"); % temporary folder for storing images

                app.centroids=cell(length(app.tifFiles));
                for fi = 1:length(app.tifFiles)

                    fileName =fullfile(app.tifFiles(fi).folder, app.tifFiles(fi).name);
                    destPath=['Pattern Recognition/tempFold/' app.tifFiles(fi).name];
                    
                    I=imread(fileName);
                    I=imadjust(I);
                    %finds new centroids in this file 
                    %for ea base centroid in fi row of baseCentroids array,
                    %calls adjustCentroids funct and stores new centroid
                    %coords in fi row of centroids array
                    [~, app.centroids{fi}]  = ImageUtil.adjustCentroids(I, app.baseCentroids);
                    %adjusts line in this file to be relative to centroids
                    %takes base lines (based on centroids)
                    [bwmask, app.aX1{fi}, app.aX2{fi}, app.aY1{fi}, app.aY2{fi}] = ImageUtil.adjustLines (I, app.centroids{fi}, ...
                        app.finalX1, app.finalX2, app.finalY1, app.finalY2, sprintf('f%i',fi), false);
                    

                    ImageUtil.syncImage( I, fileName, destPath, app.centroids{fi}, ...
                        app.aX1{fi}, app.aX2{fi}, app.aY1{fi}, app.aY2{fi}, bwmask, true);
                end
                app.tifFiles = dir(['Pattern Recognition/tempFold/*' '.tif']);
                displayImage(app, 1);
            catch ex
                msgbox("There was an error in selecting your folder. Please try again.");
                fprintf(1,'The identifier was:\n%s',ex.identifier);
                fprintf(1,'There was an error! The message was:\n%s',ex.message);
                disp(["Error stack:" getReport(ex,'extended')]);
            end
        end

        % Value changing function: ImageSlider
        function ImageSliderValueChanging(app, event)
            if not(app.numImages)
                return;
            end

            y = event.Value;
            displayImage(app, y);
        end

        % Button pushed function: left
        function leftPressed(app, event)
            if not(app.numImages)
                return;
            end

            y = max(app.ImageSlider.Value-1, 1);
            displayImage(app, y);
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            if not(app.numImages)
                return;
            end

            y = min(app.ImageSlider.Value+1, app.numImages);
            displayImage(app, y);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.play = not(app.play);
            if app.play
                app.PlayButton.Text = 'Stop';
                start(app.playTimer);

            else
                app.PlayButton.Text = 'Play';
                stop(app.playTimer);

            end


        end

        % Button pushed function: Step3GetintensitiesButton
        function GetIntensities(app, event)
            % TODO - verify

            %intensity code
            rootFolder = app.myFolder; %'Pattern Recognition/tempFold/';
    %        rootFolder = '/Users/sijiepeng/Downloads/rm/hrm';
            imageType = '.tif';
            rootFolderList = dir([rootFolder '/*' imageType]);
            rootFolderList = rootFolderList(1:end);

            fprintf(1, 'Number of records: Number of files: %i, centroids: %i, lines: %i returned for GetIntensities', ...
                length(rootFolderList),length(app.centroids{1}), length(app.finalX1)) ;
            
            intensity=zeros(length(rootFolderList), length(app.finalX1));
            bg=zeros(size(intensity));
            for fi = 1:length(rootFolderList)
                fileName = [rootFolder '/' rootFolderList(fi).name];
                currImage = imread(fileName);

%                tempC= app.centroids{fi};
%                 if(length(tempC) ~= length(app.finalX1) )
%                     fprintf(1, 'in %ith number of centroid is not equal to number of lines: %i vs %i', ...
%                         fi, length(tempC),length(app.finalX1));
%                 end
                [X1, X2, Y1, Y2]=deal(app.aX1{fi}, app.aX2{fi}, app.aY1{fi},app.aY2{fi});
                [intensity(fi, :), bg(fi,:)] =ImageUtil.linesIntensities(currImage, ...
                    app.centroids{fi}, X1, X2, Y1, Y2, NaN);
            end

%           ImageUtil.graphPlot(intensity);
%             saveas(gcf, 'Pattern Recognition/tempFold/lineIntensityGraph');
%           
%             ImageUtil.write(intensity);
            %cell=[1, 2, 3];
            cell =[];
            ImageUtil.graphPlot(intensity,'intensity', cell);
            ImageUtil.graphPlot(bg,'mean bg intensity',cell);
            measured = intensity+bg;
            ImageUtil.graphPlot(measured,'Measured intensity',cell);
            comp = measured./bg;
            ImageUtil.graphPlot(comp,'measured line intensity/background',cell);
            ImageUtil.write(intensity, 'intensity.xlsx');
            ImageUtil.write(measured, 'measured-intensity.xlsx');
            ImageUtil.write(bg, 'bgintensity.xlsx');
            ImageUtil.write(comp, 'intensity-ratio.xlsx');
   

        end

        % Button pushed function: 
        % Step1ChooseImagewithObviousLineButton
        function Step1ChooseImagewithObviousLineButtonPushed(app, event)

            try
                filterspec = '*.tif';
                [f, p] = uigetfile(filterspec);
                %concatenates file and path
                lineFile = [p f];
                app.cellCrop(lineFile);
            catch ex
                uiwait(msgbox("There was an error in selecting your files. Please try again."));
                disp(getReport(ex,'extended'));
            end

        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            currFolder = uigetdir('C:');  % get file
            figure(app.UIFigure); % focus window again
            fileType = '.tif';
            allFiles = dir([currFolder '/*' fileType]);
            mkdir('Pattern Recognition/updatedImages');
            for i = 1:length(allFiles)
                image = imread([allFiles(i).folder '/' allFiles(i).name]);
                image = imadjust(image);
                imwrite(image, ['Pattern Recognition/updatedImages/' allFiles(i).name])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PatternRecognitionTab = uitab(app.TabGroup);
            app.PatternRecognitionTab.Title = 'Pattern Recognition';
            app.UIAxes = uiaxes(app.PatternRecognitionTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImageLabel = uilabel(app.PatternRecognitionTab);
            app.ImageLabel.Text = 'Image';
            app.ImageSlider = uislider(app.PatternRecognitionTab);
            app.ImageSlider.Limits = [0 200];
            app.ImageSlider.ValueChangingFcn = createCallbackFcn(app, @ImageSliderValueChanging, true);
            app.ImageSlider.Value = 1;
            app.Step2LoadFolderwithImagesButton = uibutton(app.PatternRecognitionTab, 'push');
            app.Step2LoadFolderwithImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFile, true);
            app.Step2LoadFolderwithImagesButton.Text = {'Step 2: Load Folder with Images'; ''};
            app.left = uibutton(app.PatternRecognitionTab, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftPressed, true);
            app.left.Text = '<';
            app.right = uibutton(app.PatternRecognitionTab, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = '>';
            app.Step3GetintensitiesButton = uibutton(app.PatternRecognitionTab, 'push');
            app.Step3GetintensitiesButton.ButtonPushedFcn = createCallbackFcn(app, @GetIntensities, true);
            app.Step3GetintensitiesButton.Text = 'Step 3: Get intensities';
            app.PlayButton = uibutton(app.PatternRecognitionTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Step1ChooseImagewithObviousLineButton = uibutton(app.PatternRecognitionTab, 'push');
            app.Step1ChooseImagewithObviousLineButton.ButtonPushedFcn = createCallbackFcn(app, @Step1ChooseImagewithObviousLineButtonPushed, true);
            app.Step1ChooseImagewithObviousLineButton.Text = 'Step 1: Choose Image with Obvious Line';
            app.ImageAdjustTab = uitab(app.TabGroup);
            app.ImageAdjustTab.Title = 'Image Adjust';
            app.LoadFileButton = uibutton(app.ImageAdjustTab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PatternRecognition5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end